classdef spike_sorting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        DataPathEditFieldLabel  matlab.ui.control.Label
        DataPathEditField       matlab.ui.control.EditField
        RunButton               matlab.ui.control.Button
        SelectClusterxButton    matlab.ui.control.Button
        AddClusterxButton       matlab.ui.control.Button
        ClusterxDropDownLabel   matlab.ui.control.Label
        ClusterxDropDown        matlab.ui.control.DropDown
        FunctionListBoxLabel    matlab.ui.control.Label
        FunctionListBox         matlab.ui.control.ListBox
        RunFunctionButton       matlab.ui.control.Button
        DeleteClusterxButton    matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)

        %把所有的spikes都画出来
        function waveformsPlotting(app,option)
            global data;
            global c;
            global colors;
            global n;
            spikes_length = size(data.waveforms,2);
            spikes_max = max(data.waveforms,[],'all');
            spikes_min = min(data.waveforms,[],'all');
            t = (0.1:0.1:spikes_length/10)';
            
            switch option
                case 'Show Raw Spikes'
                    cla(app.UIAxes);
                    c = plot(app.UIAxes,t,data.waveforms','color','#0072BD');
                    k=length(c);
                    title(app.UIAxes,{'waveforms number:' k});
                    axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]);
                case 'Show n'
                    cla(app.UIAxes);
                    
                    name = split(app.ClusterxDropDown.Value,' ');
                    m = str2double(name{2});
                    index = data.idx == m;
                    
                    if size(colors,1)~=m
                        plot(app.UIAxes,t,data.waveforms(index,:)','color','#0072BD');
                    else
                        plot(app.UIAxes,t,data.waveforms(index,:)','color',colors(m,:));
                    end
                    d = find(data.idx==m);
                    k = length(d);
                    title(app.UIAxes,{'waveforms number:' k});
                    spikes_max = max(data.waveforms(index,:),[],'all');
                    spikes_min = min(data.waveforms(index,:),[],'all');
                    axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]);
                case 'Show All Clusters'
                    cla(app.UIAxes);
                    m = max(data.idx);
                    colors = distinguishable_colors(n,'w');
                    for i = 1:m
                        n_index = data.idx == i;
                        hold on;
                        plot(app.UIAxes,t,data.waveforms(n_index,:)','color',colors(i,:));
                    end
                    index = data.idx ~= 0;
                    number = length(index);
                    title(app.UIAxes,{'waveforms number:' number}); 
                    axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]);
                case 'View n in data'
                    
                case 'View all in data'
                    
                case 'n''s frequency'
                    
                case 'all''s frequency'
                    
                case 'showUnsortedSpikes'
                    cla(app.UIAxes);
                    rest = find(~data.idx);
                    c(rest) = plot(app.UIAxes,t,data.waveforms(rest,:)','Color','#0072BD');
                    spikes_length = size(data.waveforms,2);
                    spikes_max = max(data.waveforms(rest,:),[],'all');
                    spikes_min = min(data.waveforms(rest,:),[],'all');  
                    k=length(rest);
                    title(app.UIAxes,{'waveforms number:' k});
                    axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]); 
            end

        end

        %{
        %spikes重合的点不再重复画
        function waveformsPlotting(app)
            global data;
            spikes_length = size(data.waveforms,2);
            spikes_max = max(data.waveforms,[],'all');
            spikes_min = min(data.waveforms,[],'all');            
            for i = 1:spikes_length
                a = unique(data.waveforms(:,i));
                scatter(app.UIAxes,i*ones(size(a,1),1),a,".",'MarkerEdgeColor','#0072BD');
                hold on;
                axis([0 spikes_length spikes_min spikes_max]);
            end
            
        end
        
        %}
    end
    
    methods (Access = private)
        
        function addClusters(app)
            global n;
            global data;
            global rectangle;
            global c;
            spikes_length = size(data.waveforms,2);
            
            m = size(data.waveforms,1);
            t1=round(rectangle.Position(1),1)*10;
            t2=round(rectangle.Position(1)+rectangle.Position(3),1)*10;
            v1=rectangle.Position(2)*ones(1,t2-t1+1);
            v2=(rectangle.Position(2)+rectangle.Position(4))*ones(1,t2-t1+1);
            
            for i = 1:m
                if data.idx(i) ~= 0
                    continue;
                end
                
                if sum(data.waveforms(i,t1:t2) >= v1 & data.waveforms(i,t1:t2) <= v2) >= 1
                    delete(c(i));
                    data.idx(i) = n;
                end
            end
            rest = find(~data.idx);          
            spikes_max = max(data.waveforms(rest,:),[],'all');
            spikes_min = min(data.waveforms(rest,:),[],'all');
            try
                axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]);   
            catch
            end
            delete(rectangle);
            k = length(rest);
            title(app.UIAxes,{'waveforms number:' k}); 
           
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ClusterxDropDown.Items = {};
            addpath(genpath('Functions'))
            addpath(genpath('MyFunctions'))
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            global n;
            global flag;
            global colors;
            flag = 0;
            n = 0;
            
            app.ClusterxDropDown.Items = {};
            
            if isempty(app.DataPathEditField.Value)
                fig = app.UIFigure;
                message = sprintf('path not found');
                uialert(fig,message,'Warning',...
                'Icon','warning');
            else
                d = uiprogressdlg(app.UIFigure,'Title','Preprocessing . . .',...
                'Indeterminate','on');
                
                spikeSorting(app.DataPathEditField.Value);
                waveformsPlotting(app,'Show Raw Spikes');
            end
     
            close(d);
        end

        % Button pushed function: SelectClusterxButton
        function SelectClusterxButtonPushed(app, event)
            global rectangle;
            global flag;
            
            if  flag == 1
                waveformsPlotting(app,'showUnsortedSpikes');
                flag = 0;
            end 
            rectangle = drawrectangle(app.UIAxes,'Color','r');
        end

        % Button pushed function: AddClusterxButton
        function AddClusterxButtonPushed(app, event)
            global n;
            global rectangle;
            if isvalid(rectangle)
               n = n + 1;
               addClusters(app);
               app.ClusterxDropDown.Items{end+1} = ['Cluster ' int2str(n)];
            end
        end

        % Button pushed function: RunFunctionButton
        function RunFunctionButtonPushed(app, event)
            value = app.FunctionListBox.Value;
      
            global flag
            flag = 1;              
            switch value
                case 'Show Raw Spikes'
                    waveformsPlotting(app,'Show Raw Spikes');
                case 'Show x'
                    waveformsPlotting(app,"Show n");
                case 'Show All Clusters'
                    waveformsPlotting(app,"Show All Clusters");
                case 'View n in data'
                    
                case 'View all in data'
                    
                case 'n''s frequency'
                    
                case 'all''s frequency'
            end         
        end

        % Value changed function: DataPathEditField
        function DataPathEditFieldValueChanged(app, event)
            value = app.DataPathEditField.Value;            
        end

        % Value changed function: ClusterxDropDown
        function ClusterxDropDownValueChanged(app, event)
            value = app.ClusterxDropDown.Value; 
        end

        % Button pushed function: DeleteClusterxButton
        function DeleteClusterxButtonPushed(app, event)
            global data;
            global flag;
            global c;
            global n;
            
            name = split(app.ClusterxDropDown.Value,' ');
            m = str2double(name{2});
            
            index = data.idx == m;
            
            data.idx(index) = 0;                
            data.idx(data.idx>m) = data.idx(data.idx>m)-1;  
            app.ClusterxDropDown.Items(:,end) = [];
            n = n-1;
            
            if flag == 0
                hold(app.UIAxes);
                spikes_length = size(data.waveforms,2);
                t = (0.1:0.1:spikes_length/10)';
                
                c(index) = plot(app.UIAxes,t,data.waveforms(index,:)','Color','#0072BD');
                
                rest = data.idx == 0;
                spikes_max = max(data.waveforms(rest,:),[],'all');
                spikes_min = min(data.waveforms(rest,:),[],'all');  
                k=length(rest);
                title(app.UIAxes,{'waveforms number:' k});
                axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]); 
            else
                waveformsPlotting(app,'showUnsortedSpikes');
                flag = 0;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {446, 446};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataPathEditFieldLabel = uilabel(app.LeftPanel);
            app.DataPathEditFieldLabel.Text = 'Data Path';
            app.DataPathEditField = uieditfield(app.LeftPanel, 'text');
            app.DataPathEditField.ValueChangedFcn = createCallbackFcn(app, @DataPathEditFieldValueChanged, true);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectClusterxButton = uibutton(app.LeftPanel, 'push');
            app.SelectClusterxButton.ButtonPushedFcn = createCallbackFcn(app, @SelectClusterxButtonPushed, true);
            app.SelectClusterxButton.Text = 'Select Cluster x';
            app.AddClusterxButton = uibutton(app.LeftPanel, 'push');
            app.AddClusterxButton.ButtonPushedFcn = createCallbackFcn(app, @AddClusterxButtonPushed, true);
            app.AddClusterxButton.Text = 'Add Cluster x';
            app.ClusterxDropDownLabel = uilabel(app.LeftPanel);
            app.ClusterxDropDownLabel.Text = 'Cluster x';
            app.ClusterxDropDown = uidropdown(app.LeftPanel);
            app.ClusterxDropDown.Items = {};
            app.ClusterxDropDown.ValueChangedFcn = createCallbackFcn(app, @ClusterxDropDownValueChanged, true);
            app.ClusterxDropDown.Value = {};
            app.FunctionListBoxLabel = uilabel(app.LeftPanel);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.LeftPanel);
            app.FunctionListBox.Items = {'Show Raw Spikes', 'Show x', 'Show All Clusters', 'View x in data', 'View all in data', 'n''s frequency', 'all''s frequency'};
            app.FunctionListBox.Value = 'Show Raw Spikes';
            app.RunFunctionButton = uibutton(app.LeftPanel, 'push');
            app.RunFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @RunFunctionButtonPushed, true);
            app.RunFunctionButton.Text = 'Run Function';
            app.DeleteClusterxButton = uibutton(app.LeftPanel, 'push');
            app.DeleteClusterxButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteClusterxButtonPushed, true);
            app.DeleteClusterxButton.Text = 'Delete Cluster x';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'Time(ms)')
            ylabel(app.UIAxes, 'Voltage (mV)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spike_sorting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end